classdef ErrorCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MDErrorCalculatorUIFigure      matlab.ui.Figure
        LoadingsizedataPanel           matlab.ui.container.Panel
        N_totEditField                 matlab.ui.control.NumericEditField
        N_totLabel                     matlab.ui.control.Label
        NintervalEditField             matlab.ui.control.NumericEditField
        NintervalEditFieldLabel        matlab.ui.control.Label
        N_totisEditField               matlab.ui.control.EditField
        N_totisEditFieldLabel          matlab.ui.control.Label
        AnalyseButton                  matlab.ui.control.Button
        FileEditField                  matlab.ui.control.EditField
        FileEditFieldLabel             matlab.ui.control.Label
        LoaddataButton                 matlab.ui.control.Button
        DirectcalculationPanel         matlab.ui.container.Panel
        CalculateButton                matlab.ui.control.Button
        NEditField                     matlab.ui.control.NumericEditField
        NEditFieldLabel                matlab.ui.control.Label
        ErrorlimitEditField            matlab.ui.control.NumericEditField
        ErrorlimitEditField_2Label     matlab.ui.control.Label
        GSDEditField                   matlab.ui.control.NumericEditField
        GSDEditFieldLabel              matlab.ui.control.Label
        ConfidencelevelEditField       matlab.ui.control.NumericEditField
        ConfidencelevelEditFieldLabel  matlab.ui.control.Label
        DiametertypeDropDown           matlab.ui.control.DropDown
        DiametertypeDropDownLabel      matlab.ui.control.Label
        UIAxesError                    matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = public)
        GSD % geometrical standard deviation
        s % log(GSD)
        CMD % mean diameter
        P % Confidence level, usually 0.95
        u % Constant related to P
        Diameter_type %
        a % CMD a=1; VMD a=3; SMD a=5; MMD a=6
        b % Basis number
        err_limit % 0.05, 0.1, 0.2
        N % Sample size

        D % Size data
        N_tot % Total particle number
        N_delta % Sampling interval

    end

    % 自定义功能函数实现
    methods (Static)

        function [N_neg,N_pos] = calc_Ncrt_newModel(GSD,a,b,u,err_D_neg,err_D_pos)

            function e_neg = get_err_neg(N)
                [e_neg, ~] = calc_err_newModel(GSD, a, b, u, N);
            end

            function e_pos = get_err_pos(N)
                [~, e_pos] = calc_err_newModel(GSD, a, b, u, N);
            end

            % Solve N_neg ---------------------------------------------------------
            
            N_test = 1;
            max_val_neg = get_err_neg(N_test);   
            min_val_neg = get_err_neg(1e12);      
            if err_D_neg < 0 || err_D_neg < min_val_neg || err_D_neg > max_val_neg
                error('err_D_neg exceeds [%g, %g]', min_val_neg, max_val_neg);
            end

          
            N_low = 1;
            N_high = 1e12;  
            f_low = get_err_neg(N_low) - err_D_neg;
            f_high = get_err_neg(N_high) - err_D_neg;
            while sign(f_low) == sign(f_high) && N_high < 1e18
                N_high = N_high * 10;
                f_high = get_err_neg(N_high) - err_D_neg;
            end
            if sign(f_low) == sign(f_high)
                error('Unable to find interval containing zero point, please check the parameter or target error');
            end
            N_neg = fzero(@(N) get_err_neg(N) - err_D_neg, [N_low, N_high]);

            % Solve N_pos ---------------------------------------------------------
           
            max_val_pos = get_err_pos(N_test);   
            min_val_pos = get_err_pos(1e12);     
            if err_D_pos < 0 || err_D_pos > max_val_pos || err_D_pos < min_val_pos
                error('err_D_pos exceeds [%g, %g]', min_val_pos, max_val_pos);
            end

            N_low = 1;
            N_high = 1e12;
            f_low = get_err_pos(N_low) - err_D_pos;
            f_high = get_err_pos(N_high) - err_D_pos;
            while sign(f_low) == sign(f_high) && N_high < 1e18
                N_high = N_high * 10;
                f_high = get_err_pos(N_high) - err_D_pos;
            end
            if sign(f_low) == sign(f_high)
                error('Unable to find interval containing zero point, please check the parameter or target error');
            end
            N_pos = fzero(@(N) get_err_pos(N) - err_D_pos, [N_low, N_high]);

            N_neg = ceil(N_neg);
            N_pos = ceil(N_pos);

        end

        function [err_D_neg,err_D_pos] = calc_err_newModel(GSD,a,b,u,N)

            sigma = log(GSD);
            c = a / 2 + b;
            Err_masuda = u * a * sigma * sqrt(2 * c^2 * sigma^2 + 1) / sqrt(N);

            Err_masuda_D = exp(log(1+Err_masuda)/a)-1;

            x0 = sigma;
            x1 = N;
            x2 = a;

            err_D_neg = Err_masuda_D * 2.9663208 ^ ((x2 - 2.5194938) / ((sqrt(x1 / ((x2 ^ 1.5548342) ^ x0)) / x0) + x2));
            err_D_pos = Err_masuda_D * exp(x0.^1.3541945 * (x2/(x1^0.32286704))^1.189809);


        end


    end

    methods (Access = private)

        function results = func(app)

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.b = 0;
            value = app.DiametertypeDropDown.Value;
            switch value
                case 'CMD (a=1)'
                    app.a = 1;
                case 'VMD (a=3)'
                    app.a = 3;
                case 'SMD (a=5)'
                    app.a = 5;
                case 'MMD (a=6)'
                    app.a = 6;
            end

        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.P = app.ConfidencelevelEditField.Value;
            app.u = -norminv( (1-app.P)/2 );
            app.GSD = app.GSDEditField.Value;
            app.s = log(app.GSD);
            app.err_limit = app.ErrorlimitEditField.Value;

            [N_crt_neg, N_crt_pos] = calc_Ncrt_newModel(app.GSD, app.a, app.b, app.u,app.err_limit, app.err_limit);
            app.N = max(N_crt_neg,N_crt_pos);
            app.NEditField.Value = app.N;

        end

        % Value changed function: DiametertypeDropDown
        function DiametertypeDropDownValueChanged(app, event)
            value = app.DiametertypeDropDown.Value;
            switch value
                case 'CMD (a=1)'
                    app.a = 1;
                case 'VMD (a=3)'
                    app.a = 3;
                case 'SMD (a=5)'
                    app.a = 5;
                case 'MMD (a=6)'
                    app.a = 6;
            end
        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            % 打开文件选择对话框，仅允许 .xlsx 文件
            [filename, pathname] = uigetfile('*.xlsx', 'Select Excel 文件');
            if isequal(filename, 0)
                return;  % 用户取消
            end
            
            fullpath = fullfile(pathname, filename);
            app.FileEditField.Value = fullpath;
            
            
            try
                dataTable = readtable(fullpath, 'Range', 'A:A', 'ReadVariableNames', false);
                particleSize = table2array(dataTable);
                particleSize = particleSize(~isnan(particleSize));  

                app.D = particleSize;
                app.N_tot = length(app.D);
                app.N_totEditField.Value = app.N_tot;
                
                if isempty(particleSize)
                    uialert(app.MDErrorCalculatorUIFigure, 'The first column has no valid numerical data', 'Empty data');
                    return;
                end
                
               
                if any(particleSize <= 0)
                    uialert(app.MDErrorCalculatorUIFigure, 'The particle size data contains non positive values（≤0），Unable to fit logarithmic normal distribution', 'Invalid data');
                    return;
                end
            catch ME
                uialert(app.MDErrorCalculatorUIFigure, ['Failed to read file:' ME.message], 'Read error');
                return;
            end
            
           
            minVal = min(particleSize);
            maxVal = max(particleSize);
            
            numBins = min(50, round(numel(particleSize)/10));  
            logEdges = linspace(log10(minVal), log10(maxVal), numBins+1);
            binEdges = 10.^logEdges;  
            
            
            cla(app.UIAxes);
            
            histogram(app.UIAxes, particleSize, 'BinEdges', binEdges, ...
                'Normalization', 'pdf', 'FaceColor', [0.2 0.6 0.8], ...
                'EdgeColor', 'w', 'DisplayName', 'Raw distribution');
            hold(app.UIAxes, 'on');
            
           
            logData = log(particleSize);   
            mu = mean(logData);
            sigma = std(logData);
            
            x_fit_log = linspace(log(minVal), log(maxVal), 500);
            x_fit = exp(x_fit_log);
            
            pdf_lognorm = (1 ./ (x_fit * sigma * sqrt(2*pi))) .* exp(-(log(x_fit) - mu).^2 / (2*sigma^2));
            plot(app.UIAxes, x_fit, pdf_lognorm, 'r-', 'LineWidth', 2, 'DisplayName', 'LND Fit');
            
            
            app.UIAxes.XScale = 'log';
            xlabel(app.UIAxes, 'Diameter (\mum)');
            ylabel(app.UIAxes, 'Density');
            title(app.UIAxes, 'Size distribution');
            legend(app.UIAxes, 'Location', 'best');
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'off');
        
        end

        % Button pushed function: AnalyseButton
        function AnalyseButtonPushed(app, event)
            app.P = app.ConfidencelevelEditField.Value;
            app.u = -norminv( (1-app.P)/2 );
            app.err_limit = app.ErrorlimitEditField.Value;
            app.N_delta = app.NintervalEditField.Value;
            N_current = app.N_delta:app.N_delta:app.N_tot;
            N_current = N_current';
            GSD_t = zeros(length(N_current),1);
            CMD_t = zeros(length(N_current),1);
            D_exp = zeros(length(N_current),1);
            Err_neg_t = zeros(length(N_current),1);
            Err_pos_t = zeros(length(N_current),1);
            for i = 1:length(N_current)
                X_current = app.D(1:N_current(i));
                pd = fitdist(X_current, 'Lognormal');
                GSD_t(i,1) = exp(pd.sigma);
                CMD_t(i,1) = exp(pd.mu);
                D_exp(i,1) = exp(log(CMD_t(i,1)) + (app.a/2+app.b) * (log(GSD_t(i,1)))^2);
                [Err_neg_t(i,1),Err_pos_t(i,1)] = calc_err_newModel(GSD_t(i,1),app.a,app.b,app.u,N_current(i,1));
            end

            cla(app.UIAxesError);
            hold(app.UIAxesError, 'on');
            plot(app.UIAxesError, N_current, Err_pos_t*100, 'r-', 'LineWidth', 2);            
            plot(app.UIAxesError, N_current, -1*Err_neg_t*100, 'b-', 'LineWidth', 2);
            yline(app.UIAxesError, app.err_limit*100, 'k--', 'LineWidth', 1);
            yline(app.UIAxesError, -app.err_limit*100, 'k--', 'LineWidth', 1);
            app.UIAxesError.XScale = 'log';
            ylim(app.UIAxesError, [-50, 50]);
            legend(app.UIAxesError, 'off');
            grid(app.UIAxesError, 'on');
            hold(app.UIAxesError, 'off');

            Err_D = max(Err_neg_t(end,1),Err_pos_t(end,1));
            if Err_D < app.err_limit
                app.N_totisEditField.Value = 'Enough!';
            else
                app.N_totisEditField.Value = 'Not Enough!!!';
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MDErrorCalculatorUIFigure = uifigure('Visible', 'off');
            app.MDErrorCalculatorUIFigure.Name = 'MDErrorCalculator';
            app.UIAxes = uiaxes(app.MDErrorCalculatorUIFigure);
            title(app.UIAxes, 'Distribution')
            xlabel(app.UIAxes, 'Diameter')
            ylabel(app.UIAxes, 'Density')
            zlabel(app.UIAxes, 'Z')
            app.UIAxesError = uiaxes(app.MDErrorCalculatorUIFigure);
            title(app.UIAxesError, 'Error result')
            xlabel(app.UIAxesError, 'Sample size N')
            ylabel(app.UIAxesError, 'Relative error /%')
            zlabel(app.UIAxesError, 'Z')
            app.DirectcalculationPanel = uipanel(app.MDErrorCalculatorUIFigure);
            app.DirectcalculationPanel.Title = 'Direct calculation';
            app.DiametertypeDropDownLabel = uilabel(app.DirectcalculationPanel);
            app.DiametertypeDropDownLabel.Text = 'Diameter type';
            app.DiametertypeDropDown = uidropdown(app.DirectcalculationPanel);
            app.DiametertypeDropDown.Items = {'CMD (a=1)', 'VMD (a=3)', 'SMD (a=5)', 'MMD (a=6)'};
            app.DiametertypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DiametertypeDropDownValueChanged, true);
            app.DiametertypeDropDown.Value = 'MMD (a=6)';
            app.ConfidencelevelEditFieldLabel = uilabel(app.DirectcalculationPanel);
            app.ConfidencelevelEditFieldLabel.Text = 'Confidence level';
            app.ConfidencelevelEditField = uieditfield(app.DirectcalculationPanel, 'numeric');
            app.ConfidencelevelEditField.Limits = [0.5 1];
            app.ConfidencelevelEditField.Editable = 'off';
            app.ConfidencelevelEditField.Value = 0.95;
            app.GSDEditFieldLabel = uilabel(app.DirectcalculationPanel);
            app.GSDEditFieldLabel.Text = 'GSD';
            app.GSDEditField = uieditfield(app.DirectcalculationPanel, 'numeric');
            app.GSDEditField.Limits = [1.2 10];
            app.GSDEditField.Value = 3.2;
            app.ErrorlimitEditField_2Label = uilabel(app.DirectcalculationPanel);
            app.ErrorlimitEditField_2Label.Text = 'Error limit';
            app.ErrorlimitEditField = uieditfield(app.DirectcalculationPanel, 'numeric');
            app.ErrorlimitEditField.Value = 0.05;
            app.NEditFieldLabel = uilabel(app.DirectcalculationPanel);
            app.NEditFieldLabel.Text = 'N*';
            app.NEditField = uieditfield(app.DirectcalculationPanel, 'numeric');
            app.CalculateButton = uibutton(app.DirectcalculationPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.LoadingsizedataPanel = uipanel(app.MDErrorCalculatorUIFigure);
            app.LoadingsizedataPanel.Title = 'Loading size data';
            app.LoaddataButton = uibutton(app.LoadingsizedataPanel, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.FileEditFieldLabel = uilabel(app.LoadingsizedataPanel);
            app.FileEditFieldLabel.Text = 'File:';
            app.FileEditField = uieditfield(app.LoadingsizedataPanel, 'text');
            app.AnalyseButton = uibutton(app.LoadingsizedataPanel, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButtonPushed, true);
            app.AnalyseButton.Text = 'Analyse';
            app.N_totisEditFieldLabel = uilabel(app.LoadingsizedataPanel);
            app.N_totisEditFieldLabel.Text = 'N_tot is:';
            app.N_totisEditField = uieditfield(app.LoadingsizedataPanel, 'text');
            app.NintervalEditFieldLabel = uilabel(app.LoadingsizedataPanel);
            app.NintervalEditFieldLabel.Text = 'N interval';
            app.NintervalEditField = uieditfield(app.LoadingsizedataPanel, 'numeric');
            app.NintervalEditField.Value = 100;
            app.N_totLabel = uilabel(app.LoadingsizedataPanel);
            app.N_totLabel.Text = 'N_tot:';
            app.N_totEditField = uieditfield(app.LoadingsizedataPanel, 'numeric');
            % Show the figure after all components are created
            app.MDErrorCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ErrorCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MDErrorCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MDErrorCalculatorUIFigure)
        end
    end
end